classdef StarIDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StarIDUIFigure       matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        DisconnectButton     matlab.ui.control.Button
        GetImageButton       matlab.ui.control.Button
        StarIDLamp           matlab.ui.control.Lamp
        StarIDLampLabel      matlab.ui.control.Label
        ConnectLamp          matlab.ui.control.Lamp
        ConnectLampLabel     matlab.ui.control.Label
        LogTextArea          matlab.ui.control.TextArea
        SaveLogButton        matlab.ui.control.Button
        StartStarIDButton    matlab.ui.control.Button
        ServerConnectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Params = struct; % Description
        client;
        StarMat;
        pixX;
        pixY;
        FoVx;
        FoVy;
        Threshold = 20;
        ThresholdMag;
        oldpath = addpath(genpath('../'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
            % TCP/IP 서버와 연결 
            if isa(app.client,'tcpclient') == 0
                app.client = TCPClient('localhost', 10000);
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 1
                app.ConnectLamp.Color = [0 1 0];
            end
        end

        % Button pushed function: StartStarIDButton
        function StartStarIDButtonPushed(app, event)
            % 받아온 이미지로 별 인식 실행
            if isa(app.client,'tcpclient') == 1
                [PyFlag] = main_StarID(app.StarMat, app.Threshold, app.UIAxes, app.Params);
            end

        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            % TCP/IP 클라이언트가 열려있고, 받을 수 있는 데이터가 0보다 크면 실행.
            if isa(app.client,'tcpclient') == 1 && app.client.NumBytesAvailable > 0
                % cla 리셋을 해 줌으로써 플롯의 위치가 틀어지지 않는다.
                cla(app.UIAxes,'reset');
                [app.StarMat, app.pixX, app.pixY, app.FoVx, app.FoVy,app.ThresholdMag] = TCPIPReadData(app.client);
                % 통신을 통해 들어온 픽셀수 FOV 값을 main에 넘기기 위해 스트럭쳐에집어넣음.
                app.Params.pixX = app.pixX;
                app.Params.pixY = app.pixY;
                app.Params.FoVx = app.FoVx;
                app.Params.FoVy = app.FoVy;
                app.Params.ThresholdMag = app.ThresholdMag;

                imshow(app.StarMat, 'parent', app.UIAxes);
           end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 1
%                 TCPIPDisconnect(app.client);
                clear app.client;
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 0
                app.ConnectLamp.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StarIDUIFigure = uifigure('Visible', 'off');
            app.StarIDUIFigure.Name = 'StarID';
            app.StarIDUIFigure.Icon = 'Icon2.png';
            app.GridLayout = uigridlayout(app.StarIDUIFigure);
            app.ServerConnectButton = uibutton(app.GridLayout, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'ServerConnect';
            app.StartStarIDButton = uibutton(app.GridLayout, 'push');
            app.StartStarIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartStarIDButtonPushed, true);
            app.StartStarIDButton.Text = 'Start StarID';
            app.SaveLogButton = uibutton(app.GridLayout, 'push');
            app.SaveLogButton.Text = 'Save Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.ConnectLampLabel = uilabel(app.GridLayout);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.GridLayout);
            app.StarIDLampLabel = uilabel(app.GridLayout);
            app.StarIDLampLabel.Text = 'StarID';
            app.StarIDLamp = uilamp(app.GridLayout);
            app.GetImageButton = uibutton(app.GridLayout, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'GetImage';
            app.DisconnectButton = uibutton(app.GridLayout, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Panel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.StarIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarIDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StarIDUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StarIDUIFigure)
        end
    end
end