classdef StarmapSimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StarmapSIMUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ServerText                   matlab.ui.control.TextArea
        ConnectLamp                  matlab.ui.control.Lamp
        ServerLamp                   matlab.ui.control.Lamp
        ServerONButton               matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        MagLimitEditFieldLabel       matlab.ui.control.Label
        MagLimitEditField            matlab.ui.control.NumericEditField
        FoVyEditFieldLabel           matlab.ui.control.Label
        FoVyEditField                matlab.ui.control.NumericEditField
        FoVxEditFieldLabel           matlab.ui.control.Label
        FoVxEditField                matlab.ui.control.NumericEditField
        ROTEditFieldLabel            matlab.ui.control.Label
        ROTEditField                 matlab.ui.control.NumericEditField
        DECEditFieldLabel            matlab.ui.control.Label
        DECEditField                 matlab.ui.control.NumericEditField
        ImagesizeDropDownLabel       matlab.ui.control.Label
        ImagesizeDropDown            matlab.ui.control.DropDown
        SavedirEditFieldLabel        matlab.ui.control.Label
        SavedirEditField             matlab.ui.control.EditField
        RAEditFieldLabel             matlab.ui.control.Label
        RAEditField                  matlab.ui.control.NumericEditField
        SaveimgButton                matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        BlurTab                      matlab.ui.container.Tab
        K3EditFieldLabel             matlab.ui.control.Label
        K3EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        K1EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        SigmaEditField               matlab.ui.control.NumericEditField
        TextArea_2                   matlab.ui.control.TextArea
        ButtonGroup_Blur             matlab.ui.container.ButtonGroup
        DisableButton_Blur           matlab.ui.control.RadioButton
        EnableButton_Blur            matlab.ui.control.RadioButton
        TextArea_5                   matlab.ui.control.TextArea
        BackNoiseTab                 matlab.ui.container.Tab
        ButtonGroup_Noise            matlab.ui.container.ButtonGroup
        DisableButton_Noise          matlab.ui.control.RadioButton
        EnableButton_Noise           matlab.ui.control.RadioButton
        TextArea                     matlab.ui.control.TextArea
        GmaxEditField                matlab.ui.control.NumericEditField
        GmaxEditFieldLabel           matlab.ui.control.Label
        GminEditField                matlab.ui.control.NumericEditField
        GminEditFieldLabel           matlab.ui.control.Label
        MissingStarTab               matlab.ui.container.Tab
        MissingNumMaxEditField       matlab.ui.control.NumericEditField
        MissingNumMaxEditFieldLabel  matlab.ui.control.Label
        MissingNumMinEditField       matlab.ui.control.NumericEditField
        MissingNumMinEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_Missing          matlab.ui.container.ButtonGroup
        DisableButton_Missing        matlab.ui.control.RadioButton
        EnableButton_Missing         matlab.ui.control.RadioButton
        TextArea_4                   matlab.ui.control.TextArea
        RandomObjTab                 matlab.ui.container.Tab
        ObjNumMaxEditField           matlab.ui.control.NumericEditField
        ObjNumMaxEditField_2Label    matlab.ui.control.Label
        ObjRadiusMaxEditField        matlab.ui.control.NumericEditField
        ObjRadiusMaxEditFieldLabel   matlab.ui.control.Label
        ObjRadiusMinEditField        matlab.ui.control.NumericEditField
        ObjRadiusMinEditFieldLabel   matlab.ui.control.Label
        ObjNumMinEditField           matlab.ui.control.NumericEditField
        ObjNumMinEditFieldLabel      matlab.ui.control.Label
        ButtonGroup_RandObj          matlab.ui.container.ButtonGroup
        DisableButton_RandObj        matlab.ui.control.RadioButton
        EnableButton_RandObj         matlab.ui.control.RadioButton
        TextArea_3                   matlab.ui.control.TextArea
        CenterPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        StatusTextAreaLabel          matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        ClearLogButton               matlab.ui.control.Button
        SaveLogButton                matlab.ui.control.Button
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        Params;
        pixX = 1024;
        pixY = 1024;
        StarImage = 0;
        SaveImagePath = './';
        StarImageFlag = 0;
        server;
        SendMatix;
        temp1=1;
        oldpath = addpath(genpath('../'));
    end

    properties (Access = public)

    end

    methods (Access = private)

        function [Params] = SetParam(app)
            Params.RA = app.RAEditField.Value;
            Params.DEC = app.DECEditField.Value;
            Params.ROT = app.ROTEditField.Value;
            Params.FoVx = app.FoVxEditField.Value;
            Params.FoVy = app.FoVyEditField.Value;
            Params.Mag = app.MagLimitEditField.Value;
            Params.PixelNum_X = app.pixX;
            Params.PixelNum_Y = app.pixY;
            Params.sigma = app.SigmaEditField.Value;
            Params.K1 = app.K1EditField.Value;
            Params.K2 = app.K2EditField.Value;
            Params.K3 = app.K3EditField.Value;
            Params.ActiveBlur = app.EnableButton_Blur.Value;
            Params.MaxNoise = app.GmaxEditField.Value;
            Params.MinNoise = app.GminEditField.Value;
            Params.ActiveNoise = app.EnableButton_Noise.Value;
            Params.MissingMax = app.MissingNumMaxEditField.Value;
            Params.MissingMin = app.MissingNumMinEditField.Value;
            Params.ActiveMissing = app.EnableButton_Missing.Value;
            Params.MissingNum = NaN;
            Params.RandObjMaxNum = app.ObjNumMaxEditField.Value;
            Params.RandObjMinNum = app.ObjNumMinEditField.Value;
            Params.RandObjMaxRadi = app.ObjRadiusMaxEditField.Value;
            Params.RandObjMinRadi = app.ObjRadiusMinEditField.Value;
            Params.ActiveRandObj = app.EnableButton_RandObj.Value;
            Params.RandObjNum = NaN;

        end

        %         function results = ShowStatus(app)
        %             temp = [strcat('RA:',string(app.Params.RA),'(deg) DEC:',string(app.Params.DEC), '(deg) ROT:',string(app.Params.ROT), '(deg)    MagLimit:',string(app.Params.Mag), ...
        %                 '    FoVxy:',string(app.Params.FoVx),'x',string(app.Params.FoVy),'(deg)    PixelNum_XY:',string(app.Params.PixelNum_X), 'x',string(app.Params.PixelNum_Y),...
        %                ' '),strcat('BlurInfo//','  Sigma:',string(app.Params.sigma),'  K1, K2, K3:', string(app.Params.K1),', ',string(app.Params.K2),', ',string(app.Params.K3)),...
        %                strcat('BackNoise: ',string(app.Params.MinNoise),'~',string(app.Params.MaxNoise),'(uint8) ', ...
        %                '  Missing Stars#:', '  RandomObject#:')];
        %             results = cellstr(temp);
        %         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DECEditField, FoVxEditField, 
        % FoVyEditField, MagLimitEditField, RAEditField, 
        % ROTEditField, RunButton, Slider, StatusTextArea
        function RunButtonPushed(app, event)
            % Set Parameters
            app.Params = SetParam(app);
            [app.StarImage] = main(app.Params);
            app.StatusTextArea.Value = ShowStatus(app.Params);  
            % cla 리셋을 해 줌으로써 플롯의 위치가 틀어지지 않는다.
            cla(app.UIAxes,'reset');
            imshow(app.StarImage, 'parent', app.UIAxes);
%             rectangle(app.UIAxes, 'Position', [25,50,25,50],'EdgeColor','r');

            app.StarImageFlag = 1;              
% =========================================================================
% TCP/IP 서버가 열려있고, client 와 연결되어 있으면, 보낼 데이터를 write 한다.
            if isa(app.server,'tcpserver.internal.TCPServer') == 1
                if (app.server.Connected)                    
                    app.SendMatix = reshape(app.StarImage, [1,app.pixX * app.pixY]);
                    write(app.server,app.pixX,'uint16');
                    write(app.server,app.pixY,'uint16');
                    write(app.server,app.FoVxEditField.Value,'uint8');
                    write(app.server,app.FoVyEditField.Value,'uint8');
                    write(app.server,app.MagLimitEditField.Value,'single');
                    write(app.server,app.SendMatix,'uint8');                        
                end
            end
            
        end

        % Value changed function: ImagesizeDropDown
        function ImagesizeDropDownValueChanged(app, event)
            value = app.ImagesizeDropDown.Value;
            if(value == '1024x1024 pix')
                app.pixX = 1024;
                app.pixY = 1024;
            elseif(value == '2048x2048 pix')
                app.pixX = 2048;
                app.pixY = 2048;
            elseif(value == '4096x4096 pix')
                app.pixX = 4096;
                app.pixY = 4096;
            elseif(value == ' 512x 512 pix')
                app.pixX = 512;
                app.pixY = 512;
            end
        end

        % Key press function: StarmapSIMUIFigure
        function StarmapSIMUIFigureKeyPress(app, event)
            key = event.Key;
            if isequal(event.Modifier,'shift')
                switch key
                    %                     case 'f1'
                    %                         app.ChangeData(app);
                    %                         app.Message.Value = key;
                    %                     case ...
                    %                             ...
                end
            elseif isequal(event.Modifier,'control')
                switch key
                    %                     case 'q'
                    % %                         app.Message.Value = 'quitting'; % write to text area of GUI
                    %                         % insert a quit function %
                    %                     case ...
                    %                             ...
                end
            else
                switch key
                    case 'return'
                        RunButtonPushed(app,event);
                        %                 case 'f1'
                        %                     app.ChangeData(app);
                        %                     app.Message.Value = key;
                        %                 case 'shift'
                        %                     app.ChangeColor(app);
                        %                     app.Message.Value = key;
                        %                 case '1'
                        %                     app.Message.Value = 'Pressed 1';
                end
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ROTEditField.Value = changingValue;

        end

        % Button pushed function: SaveimgButton
        function SaveimgButtonPushed(app, event)
            if (app.StarImageFlag)
                SaveFiles.Images(app.SaveImagePath,app.StarImage);
            else
                app.StatusTextArea.Value = {'No Image'};
            end

        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)

        end

        % Selection changed function: ButtonGroup_Blur
        function ButtonGroup_BlurSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Blur.SelectedObject;

        end

        % Value changed function: SavedirEditField
        function SavedirEditFieldValueChanged(app, event)
            value = app.SavedirEditField.Value;
            app.SaveImagePath = value;
        end

        % Callback function
        function LogTextAreaValueChanged(app, event)
            value = app.LogTextArea.Value;

        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.LogTextArea.Value = '';

        end

        % Button pushed function: ServerONButton
        function ServerONButtonPushed(app, event)
            
            if isa(app.server,'tcpserver.internal.TCPServer') == 0
                app.server = TCPServer.CreateTCPServer('0.0.0.0', 10000);
            end            
            if app.server.Connected
                app.ConnectLamp.Color = [0 1 0];
            else
                app.ConnectLamp.Color = [1 0 0];
            end
            
            lamptemp = isa(app.server,'tcpserver.internal.TCPServer');
            tempstr = [strcat("ServerAdr: ",app.server.ServerAddress); strcat("PortNum: ", num2str(app.server.ServerPort))];
            app.ServerText.Value = tempstr;

            if lamptemp == 1
                app.ServerLamp.Color = [0 1 0];
            else
                app.ServerLamp.Color = [1 0 0];
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.StarmapSIMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {700, 700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {341, '1x', 294};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StarmapSIMUIFigure = uifigure('Visible', 'off');
            app.StarmapSIMUIFigure.Name = 'StarmapSIM';
            app.StarmapSIMUIFigure.Icon = 'Icon1.png';
            app.StarmapSIMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.StarmapSIMUIFigure.KeyPressFcn = createCallbackFcn(app, @StarmapSIMUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.StarmapSIMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.BlurTab = uitab(app.TabGroup);
            app.BlurTab.Title = 'Blur';
            app.TextArea_5 = uitextarea(app.BlurTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Gaussian Distribution'; 'H = K1 * exp(-K2 * StarMag + K3)'};
            app.ButtonGroup_Blur = uibuttongroup(app.BlurTab);
            app.ButtonGroup_Blur.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_BlurSelectionChanged, true);
            app.EnableButton_Blur = uiradiobutton(app.ButtonGroup_Blur);
            app.EnableButton_Blur.Text = 'Enable';
            app.EnableButton_Blur.Value = true;
            app.DisableButton_Blur = uiradiobutton(app.ButtonGroup_Blur);
            app.DisableButton_Blur.Text = 'Disable';
            app.TextArea_2 = uitextarea(app.BlurTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Gaussian Distribution'};
            app.SigmaEditField = uieditfield(app.BlurTab, 'numeric');
            app.SigmaEditField.Limits = [0 10];
            app.SigmaEditField.Value = 1;
            app.SigmaEditFieldLabel = uilabel(app.BlurTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.K1EditField = uieditfield(app.BlurTab, 'numeric');
            app.K1EditField.Limits = [-10000 10000];
            app.K1EditField.Value = 1500;
            app.K1EditFieldLabel = uilabel(app.BlurTab);
            app.K1EditFieldLabel.Text = 'K1';
            app.K2EditField = uieditfield(app.BlurTab, 'numeric');
            app.K2EditField.Limits = [-10 100];
            app.K2EditField.Value = 0.6;
            app.K2EditFieldLabel = uilabel(app.BlurTab);
            app.K2EditFieldLabel.Text = 'K2';
            app.K3EditField = uieditfield(app.BlurTab, 'numeric');
            app.K3EditField.Limits = [-100 100];
            app.K3EditField.Value = 1.2;
            app.K3EditFieldLabel = uilabel(app.BlurTab);
            app.K3EditFieldLabel.Text = 'K3';
            app.BackNoiseTab = uitab(app.TabGroup);
            app.BackNoiseTab.Title = 'BackNoise';
            app.GminEditFieldLabel = uilabel(app.BackNoiseTab);
            app.GminEditFieldLabel.Text = 'Gmin';
            app.GminEditField = uieditfield(app.BackNoiseTab, 'numeric');
            app.GminEditField.Limits = [0 255];
            app.GmaxEditFieldLabel = uilabel(app.BackNoiseTab);
            app.GmaxEditFieldLabel.Text = 'Gmax';
            app.GmaxEditField = uieditfield(app.BackNoiseTab, 'numeric');
            app.GmaxEditField.Limits = [0 255];
            app.GmaxEditField.Value = 1;
            app.TextArea = uitextarea(app.BackNoiseTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Backgound Noise'};
            app.ButtonGroup_Noise = uibuttongroup(app.BackNoiseTab);
            app.EnableButton_Noise = uiradiobutton(app.ButtonGroup_Noise);
            app.EnableButton_Noise.Text = 'Enable';
            app.DisableButton_Noise = uiradiobutton(app.ButtonGroup_Noise);
            app.DisableButton_Noise.Text = 'Disable';
            app.DisableButton_Noise.Value = true;
            app.MissingStarTab = uitab(app.TabGroup);
            app.MissingStarTab.Title = 'MissingStar';
            app.TextArea_4 = uitextarea(app.MissingStarTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Random Missing Stars'};
            app.ButtonGroup_Missing = uibuttongroup(app.MissingStarTab);
            app.EnableButton_Missing = uiradiobutton(app.ButtonGroup_Missing);
            app.EnableButton_Missing.Text = 'Enable';
            app.DisableButton_Missing = uiradiobutton(app.ButtonGroup_Missing);
            app.DisableButton_Missing.Text = 'Disable';
            app.DisableButton_Missing.Value = true;
            app.MissingNumMinEditFieldLabel = uilabel(app.MissingStarTab);
            app.MissingNumMinEditFieldLabel.Text = 'Missing Num Min';
            app.MissingNumMinEditField = uieditfield(app.MissingStarTab, 'numeric');
            app.MissingNumMinEditField.Limits = [1 9999];
            app.MissingNumMinEditField.Value = 1;
            app.MissingNumMaxEditFieldLabel = uilabel(app.MissingStarTab);
            app.MissingNumMaxEditFieldLabel.Text = 'Missing Num Max';
            app.MissingNumMaxEditField = uieditfield(app.MissingStarTab, 'numeric');
            app.MissingNumMaxEditField.Limits = [1 9999];
            app.MissingNumMaxEditField.Value = 1;
            app.RandomObjTab = uitab(app.TabGroup);
            app.RandomObjTab.Title = 'RandomObj';
            app.TextArea_3 = uitextarea(app.RandomObjTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Random Objects'};
            app.ButtonGroup_RandObj = uibuttongroup(app.RandomObjTab);
            app.EnableButton_RandObj = uiradiobutton(app.ButtonGroup_RandObj);
            app.EnableButton_RandObj.Text = 'Enable';
            app.DisableButton_RandObj = uiradiobutton(app.ButtonGroup_RandObj);
            app.DisableButton_RandObj.Text = 'Disable';
            app.DisableButton_RandObj.Value = true;
            app.ObjNumMinEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjNumMinEditFieldLabel.Text = 'Obj Num Min';
            app.ObjNumMinEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjNumMinEditField.Limits = [0 100];
            app.ObjNumMinEditField.Value = 1;
            app.ObjRadiusMinEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjRadiusMinEditFieldLabel.Text = 'Obj Radius Min';
            app.ObjRadiusMinEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjRadiusMinEditField.Limits = [0 100];
            app.ObjRadiusMinEditField.Value = 1;
            app.ObjRadiusMaxEditFieldLabel = uilabel(app.RandomObjTab);
            app.ObjRadiusMaxEditFieldLabel.Text = 'Obj Radius Max';
            app.ObjRadiusMaxEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjRadiusMaxEditField.Limits = [0 100];
            app.ObjRadiusMaxEditField.Value = 1;
            app.ObjNumMaxEditField_2Label = uilabel(app.RandomObjTab);
            app.ObjNumMaxEditField_2Label.Text = 'Obj Num Max';
            app.ObjNumMaxEditField = uieditfield(app.RandomObjTab, 'numeric');
            app.ObjNumMaxEditField.Limits = [0 100];
            app.ObjNumMaxEditField.Value = 3;
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveimgButton = uibutton(app.GridLayout3, 'push');
            app.SaveimgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimgButtonPushed, true);
            app.SaveimgButton.Text = 'Save img';
            app.RAEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RAEditField.Limits = [0 360];
            app.RAEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RAEditField.Value = 85;
            app.RAEditFieldLabel = uilabel(app.GridLayout3);
            app.RAEditFieldLabel.Text = 'RA';
            app.SavedirEditField = uieditfield(app.GridLayout3, 'text');
            app.SavedirEditField.ValueChangedFcn = createCallbackFcn(app, @SavedirEditFieldValueChanged, true);
            app.SavedirEditField.Value = './';
            app.SavedirEditFieldLabel = uilabel(app.GridLayout3);
            app.SavedirEditFieldLabel.Text = 'Save dir';
            app.ImagesizeDropDown = uidropdown(app.GridLayout3);
            app.ImagesizeDropDown.Items = {' 512x 512 pix', '1024x1024 pix', '2048x2048 pix', '4096x4096 pix'};
            app.ImagesizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesizeDropDownValueChanged, true);
            app.ImagesizeDropDown.Value = '1024x1024 pix';
            app.ImagesizeDropDownLabel = uilabel(app.GridLayout3);
            app.ImagesizeDropDownLabel.Text = 'Image size';
            app.DECEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DECEditField.Limits = [-90 90];
            app.DECEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.DECEditFieldLabel = uilabel(app.GridLayout3);
            app.DECEditFieldLabel.Text = 'DEC';
            app.ROTEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ROTEditField.Limits = [0 360];
            app.ROTEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ROTEditFieldLabel = uilabel(app.GridLayout3);
            app.ROTEditFieldLabel.Text = 'ROT';
            app.FoVxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FoVxEditField.Limits = [1 180];
            app.FoVxEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.FoVxEditField.Value = 15;
            app.FoVxEditFieldLabel = uilabel(app.GridLayout3);
            app.FoVxEditFieldLabel.Text = 'FoVx';
            app.FoVyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FoVyEditField.Limits = [1 180];
            app.FoVyEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.FoVyEditField.Value = 15;
            app.FoVyEditFieldLabel = uilabel(app.GridLayout3);
            app.FoVyEditFieldLabel.Text = 'FoVy';
            app.MagLimitEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MagLimitEditField.Limits = [-100 100];
            app.MagLimitEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.MagLimitEditField.Value = 5;
            app.MagLimitEditFieldLabel = uilabel(app.GridLayout3);
            app.MagLimitEditFieldLabel.Text = 'MagLimit';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [0 360];
            app.Slider.MajorTicks = [0 90 180 270 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.ServerONButton = uibutton(app.GridLayout3, 'push');
            app.ServerONButton.ButtonPushedFcn = createCallbackFcn(app, @ServerONButtonPushed, true);
            app.ServerONButton.Text = 'Server ON';
            app.ServerLamp = uilamp(app.GridLayout3);
            app.ConnectLamp = uilamp(app.GridLayout3);
            app.ServerText = uitextarea(app.GridLayout3);
            app.ServerText.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.StatusTextArea = uitextarea(app.GridLayout2);
            app.StatusTextArea.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextAreaLabel = uilabel(app.GridLayout2);
            app.StatusTextAreaLabel.Text = 'Status';
            app.RightPanel = uipanel(app.GridLayout);
            app.LogTextAreaLabel = uilabel(app.RightPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.RightPanel);
            app.LogTextArea.Editable = 'off';
            app.SaveLogButton = uibutton(app.RightPanel, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.ClearLogButton = uibutton(app.RightPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.Menu = uimenu(app.StarmapSIMUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu없음';
            % Show the figure after all components are created
            app.StarmapSIMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarmapSimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StarmapSIMUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StarmapSIMUIFigure)
        end
    end
end